classdef Term_Project_Receiver_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SignalSelectSwitch            matlab.ui.control.Switch
        SignalSelectSwitchLabel       matlab.ui.control.Label
        DFTSampleValueEditField       matlab.ui.control.NumericEditField
        DFTSampleValueEditFieldLabel  matlab.ui.control.Label
        OverlapLengthEditField        matlab.ui.control.NumericEditField
        OverlapLengthEditFieldLabel   matlab.ui.control.Label
        WindowLengthEditField         matlab.ui.control.NumericEditField
        WindowLengthEditFieldLabel    matlab.ui.control.Label
        SNREditField                  matlab.ui.control.NumericEditField
        SNREditFieldLabel             matlab.ui.control.Label
        RecordTimeEditField           matlab.ui.control.NumericEditField
        RecordTimeEditFieldLabel      matlab.ui.control.Label
        SamplingRateDropDown          matlab.ui.control.DropDown
        SamplingRateDropDownLabel     matlab.ui.control.Label
        SignalCategoryDropDown        matlab.ui.control.DropDown
        SignalCategoryDropDownLabel   matlab.ui.control.Label
        DecodedDataTextArea           matlab.ui.control.TextArea
        DecodedDataTextAreaLabel      matlab.ui.control.Label
        RecordButton                  matlab.ui.control.Button
        Spectrogram                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        isRecording = 0;
        recordTime = 15;
        plot_which = "Unfiltered";

        samplingRate = 44100;
        recorder = audiorecorder(44100,16,1);
        recordedData;
        studentNumber = '2516987';
        signalCategory = 'Category 1';
        signal = 0;
        filtered_signal = 0;
        estimated_SNR=0;

        M_value = 4410;
        L_value = 2205;
        Ndft_value = 8820;

    end
    
    methods (Access = private)
        
        function main(app)
            app.RecordButton.Text = "Recording";
            % respahe signal
            if app.signalCategory == 'Category 1'
                 hop_period= 1;
            elseif app.signalCategory == 'Category 2'
                hop_period = 0.75;
            elseif app.signalCategory == 'Category 3'
                 hop_period = 0.5;
            end

            audioData = listen(app);

            disp("SOI Detection")
            [start_idx , end_idx] =  my_detector(app,audioData,app.samplingRate);
            app.signal = audioData(start_idx + app.samplingRate*hop_period : end_idx - app.samplingRate*hop_period);
            
            
            disp("Signal Lenght:")
            signalLength = findSignalLength(app, app.samplingRate, app.signal,app.signalCategory )
            
            
            % app.signal = app.signal(app.samplingRate*hop_period+1 : end- app.samplingRate*hop_period);


            disp("SNR Estimation")
            app.estimated_SNR = mySNR(app, audioData, start_idx, end_idx);
            app.SNREditField.Value = app.estimated_SNR;


            disp("Generated Frequency Table:")
            freqTable = generateFreqTable(app, app.studentNumber, app.signalCategory)
            disp("Selected Frequencies:")
            % selectedFreqs = selectFreqsfromTable(app, my_freqs, freqTable)
            selectedFreqs = selectFreqsfromTable2(app, signalLength, freqTable)

            %bandpass
            app.filtered_signal = signal_filter_unwanted(app, app.signal, selectedFreqs, signalLength);


            disp("Signal is plotting")
            %[tint , fint , Xint] = plot_spectrogram(app,app.signal,app.samplingRate);
            [tint , fint , Xint] = plot_spectrogram(app,app.filtered_signal,app.samplingRate);

            disp("Finding Freqs")
            my_freqs = find_freqs(app, tint , fint , Xint, hop_period)
            % my_freqs = [2100 5300 4300 5100 1600 1600 5100 1300]


            disp("Demodulated Data")
            demodulated_data = demodulate(app, selectedFreqs, my_freqs)
            disp("Original Data")
            original_data = binarytoText(app, demodulated_data)
            app.DecodedDataTextArea.Value = original_data;

            app.RecordButton.Text = "Record";
        end

        function [start_index,end_index] = my_detector(app,y,fs)

            [filtered_y,~] = bandpass(y,[14900 15100],fs,ImpulseResponse="iir",Steepness=0.95);
        
            % taking abs of signal to examine instantenous power
            abs_filtered_y = abs(filtered_y);
        
            % Normalize
            y_normalized = abs_filtered_y ./ max(abs_filtered_y);
            
            % moving avarage filter
            windowSize = length(y)/441; 
            b = (1/windowSize)*ones(1,windowSize);
            a = 1;
            smooth_y = filter(b,a,y_normalized);
        
            % Define a threshold for valid peaks
            threshold = max(smooth_y) * 0.2 + mean(smooth_y)*0; % Adjust this multiplier as needed
            
            abov_th = (smooth_y>threshold);
        
            % Find indices of all ones
            indices = find(abov_th == 1);
            
            % Get the first and last indices of ones
            start_index = indices(1); % First index of 1
            end_index = indices(end); % Last index of 1
        
            if start_index <= 0
                start_index = 1;
            end
            if end_index > length(y_normalized)
                end_index = length(y_normalized);
            end
        end

        function estimated_SNR = mySNR(app,y,start_idx,end_idx)
            % Estimate the SNR of a given noisy signal
            % Step 2: Extract the Signal-of-Interest (SOI) and noise regions
            SOI_noisy = y(start_idx:end_idx);   % Signal-of-interest (SOI)
            noise_region_1 = y(1:start_idx-1);   % Noise before SOI
            noise_region_2 = y(end_idx+1:end);   % Noise after SOI
            % Step 3: Combine both noise regions to calculate noise statistics
            noise = [noise_region_1; noise_region_2];
            % Step 4: Compute the power of the signal and noise
            % Power of the noisy SOI (signal + noise)
            SOI_noisy_power = var(SOI_noisy);
            noise_power = var(noise);  % Variance of the noise
            % Step 5: Estimate the signal power by subtracting the noise power from the noisy SOI power
            signal_power = SOI_noisy_power - noise_power;
            % Step 6: Compute the SNR (in dB)
            if signal_power > 0
                estimated_SNR = 10 * log10(signal_power / noise_power);
            else
                estimated_SNR = -Inf;  % If signal power is too low
            end
        end

        function audioData = listen(app)
            Fs = app.samplingRate;  % Sampling frequency (Hz)
            bits = 16;              % Bit resolution
            channels = 1;           % Number of channels (mono)
            
            % Create an audiorecorder object
            recObj = audiorecorder(Fs, bits, channels);

            % Start recording
            disp('Start speaking.')
            recordblocking(recObj, app.recordTime); % Record time
            disp('End of recording.')

            % Get the recorded audio data
            audioData = getaudiodata(recObj);
            
            % Save the audio to a file (optional)
            audiowrite('recorded_audio.wav', audioData, Fs);
        end

        function [tint , fint , Xint] = plot_spectrogram(app,signal,fs)
            M = app.M_value; % window length M
            L = app.L_value ; % overlap length L
            g = bartlett(M); % Window each segment with a Barlett Window
            
            Ndft = app.Ndft_value; % Evaluate the discrete Fourier transform of each segment at  N
            
            ts = 0:1/fs:(length(signal)/fs);
            segs = framesig(1:length(signal),M,OverlapLength=L);
            X = fft(signal(segs).*g,Ndft);
            % To find the time values, divide the time vector into overlapping segments. 
            % The time values are the midpoints of the segments, with each segment treated as an interval open at the lower end.
            framedT = ts(segs);
            tint = mean(framedT(2:end,:));  
            % To find the frequency values, specify a Nyquist interval closed at zero frequency and open at the lower end.
            fint = 0:fs/Ndft:fs-fs/Ndft;
            Xint = abs(X).^2;
            imagesc(app.Spectrogram,tint,fint,Xint);
            ylim(app.Spectrogram,[0,fs/2]) 
            xlim(app.Spectrogram,[0,length(signal)/fs])
        end

        function frequencies = find_freqs(app, tint, fint, Xint, hop_period)
            % Initialize the output array
            frequencies = [];
        
            % Define the interval length (0.5 seconds)
            interval_length = hop_period;
        
            % Get the total time range
            t_min = tint(1);
            t_max = tint(end);
        
            % Adjust start point to begin at the midpoint of the first interval
            start_point = t_min + interval_length / 2;
        
            % Loop through each 0.5-second interval starting at midpoint
            for start_time = start_point:interval_length:(t_max)
               
                % Define the current interval
                end_time = start_time + 0.1;
        
                % Get the indices for the current time interval
                time_indices = find(tint >= start_time & tint < end_time);
        
                % Extract the relevant portion of the amplitude data
                Xint_interval = Xint(:, time_indices);
        
                % Find the index of the maximum amplitude in this interval
                [~, max_index] = max(Xint_interval(:));
        
                % Get the frequency index and corresponding frequency
                [freq_idx, ~] = ind2sub(size(Xint_interval), max_index);
                max_frequency = fint(freq_idx);
        
                % Append the frequency to the output array
                frequencies = [frequencies, max_frequency];
            end
        end

        function freqTable = generateFreqTable(app, studentNumber, category)
            b_cat1 = str2double(studentNumber(6));
            S1 = 100*(mod(str2double(studentNumber(7)),5));
            row1 = [1000, 1500, 2000, 2500, 3000, 3500, 4000, 4500, 5000];

            b_cat2 = str2double(studentNumber(5));
            S2 = 100*str2double(studentNumber(7));
            row2 = [1500, 2500, 3500, 4500, 5500, 6500, 7500, 8500];

            S3 = 100*str2double(studentNumber(7));
            row3 = [1000, 3000, 5000, 7000, 9000, 11000];
                        
            if category == 'Category 1'
                if mod(b_cat1,2) == 0
                    b_cat1 = 1;
                else
                    b_cat1 = -1;
                end

                freqTable = row1 + b_cat1*S1; 

            elseif category == 'Category 2'
                if mod(b_cat2, 2) == 0
                    b_cat2 = 1;
                else
                    b_cat2 = -1;
                end

                freqTable = row2 + b_cat2*S2;                 

            elseif category == 'Category 3'
                freqTable = row3 + S3;
            else
                disp('WRONG CATEGORY');
            end
        end

        function selectedFreqs = selectFreqsfromTable(app, inputData, freqTable)
            [~, inputdataLength] = size(inputData);
            [~, freqtableLength] = size(freqTable);
            selectedFreqs = zeros(1, inputdataLength);
            rng(31); % Optional: Seed the random number generator for different results each time
            randomValues = randi([1, freqtableLength], 1, inputdataLength) % Generate inputdataLength random integers between 1 and freqtableLength
            for i = 1:inputdataLength 
                selectedFreqs(1, i) = freqTable(randomValues(1, i));
            end
        end
        
        function demodulated_data = demodulate(app, selectedFreqs, my_freqs, category)
            differences = my_freqs - selectedFreqs
            [~, differences_length] = size(differences);
            
            demodulated_data =  [];
            if app.signalCategory == 'Category 1'
                demodulated_data = max(differences./100, 0);
            elseif app.signalCategory == 'Category 2'
                differences = round(differences/150)*150;
                mk = differences./150;
                for i=1:differences_length
                    switch mk(i)
                       case -2
                          demodulated_data = [demodulated_data , 0 , 0];
                       case -1
                          demodulated_data = [demodulated_data , 0 , 1];
                       case 1
                          demodulated_data = [demodulated_data , 1 , 0];
                       case 2
                          demodulated_data = [demodulated_data , 1 , 1];
                       otherwise
                          disp('you fucked up')
                    end
                end

            elseif app.signalCategory == 'Category 3'
                differences = round(differences/200)*200;
                mk = differences./200;
                for i=1:length(differences)
                    switch mk(i)
                       case -4
                          demodulated_data = [demodulated_data , 0 , 0 , 0];
                       case -3
                          demodulated_data = [demodulated_data , 0 , 0 , 1];
                       case -2
                          demodulated_data = [demodulated_data , 0 , 1 , 0];
                       case -1
                          demodulated_data = [demodulated_data , 0 , 1 , 1];
                       case 1
                          demodulated_data = [demodulated_data , 1 , 0 , 0];
                       case 2
                          demodulated_data = [demodulated_data , 1 , 0 , 1];
                       case 3
                          demodulated_data = [demodulated_data , 1 , 1 , 0];
                       case 4
                          demodulated_data = [demodulated_data , 1 , 1 , 1];
                       otherwise
                          disp('you fucked up')
                    end
                end
                len = round(length(demodulated_data)/8)*8;
                demodulated_data = demodulated_data(1 : len);
            end
                     
        end
        
        function text = binarytoText(app, demodulated_data)
            bitGroups = reshape(demodulated_data, 8, []).';
            decimalValues = sum(bitGroups .* (2 .^ (7:-1:0)), 2);
            text = string(char(decimalValues)).join(''); % Converts to string and ensures one line
        end

        function signal_length = findSignalLength(app, samplingRate, signal, category)
            signallen = length(signal);
            % signal_length = round(signallen/samplingRate);
            % respahe signal
            if category == 'Category 1'
                 hop_period= 1;
            elseif category == 'Category 2'
                hop_period = 0.75;
            elseif category == 'Category 3'
                 hop_period = 0.5;
            end
            disp((signallen/samplingRate))
            disp(hop_period)
            disp((signallen/samplingRate)/hop_period)
            signal_length = round((signallen/samplingRate)/hop_period);
        end

        function selectedFreqs = selectFreqsfromTable2(app, inputLenght, freqTable)
            [~, freqtableLength] = size(freqTable);
            selectedFreqs = zeros(1, inputLenght);
            rng(31); % Optional: Seed the random number generator for different results each time
            randomValues = randi([1, freqtableLength], 1, inputLenght) % Generate inputdataLength random integers between 1 and freqtableLength
            for i = 1:inputLenght 
                selectedFreqs(1, i) = freqTable(randomValues(1, i));
            end
        end
        function filtered_signal = signal_filter_unwanted(app, signal, selected_freqs, signal_length)
            % Ensure the input signal can be split into equal segments
            total_length = length(signal);
            segment_size = floor(total_length / signal_length);
        
            % Initialize the filtered signal
            filtered_signal = zeros(size(signal));
            
            % Loop through each segment
            for i = 1:signal_length
                % Determine the range of indices for this segment
                start_idx = (i - 1) * segment_size + 1;
                if i == signal_length
                    % Include the remaining samples in the last segment
                    end_idx = total_length;
                else
                    end_idx = i * segment_size;
                end
                
                % Extract the segment
                segment = signal(start_idx:end_idx);
                
                % Get the frequency for this segment
                center_freq = selected_freqs(i);
                
                % Design a band-pass filter around the center frequency
                fs = app.samplingRate; % Replace with the actual sample rate
                if app.signalCategory == 'Category 1'
                     bandwidth= 150;
                elseif app.signalCategory == 'Category 2'
                    bandwidth= 350;
                elseif app.signalCategory == 'Category 3'
                    bandwidth= 850;
                end


                [b, a] = butter(4, [(center_freq - bandwidth) / (fs / 2), ...
                                    (center_freq + bandwidth) / (fs / 2)], 'bandpass');
                
                % Filter the segment
                filtered_segment = filtfilt(b, a, segment);
                
                % Insert the filtered segment back into the output signal
                filtered_signal(start_idx:end_idx) = filtered_segment;
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            main(app);
        end

        % Value changed function: RecordTimeEditField
        function RecordTimeEditFieldValueChanged(app, event)
            app.recordTime = app.RecordTimeEditField.Value;
            disp("Record Time Value Changed")
            
        end

        % Value changed function: WindowLengthEditField
        function WindowLengthEditFieldValueChanged(app, event)
            app.M_value = app.WindowLengthEditField.Value;
            if(app.plot_which == "Unfiltered")
                plot_spectrogram(app,app.signal,app.samplingRate);
            else 
                plot_spectrogram(app,app.filtered_signal,app.samplingRate);
            end
            disp("Window Length Value Changed")
            
        end

        % Value changed function: OverlapLengthEditField
        function OverlapLengthEditFieldValueChanged(app, event)
            app.L_value = app.OverlapLengthEditField.Value;
            if(app.plot_which == "Unfiltered")
                plot_spectrogram(app,app.signal,app.samplingRate);
            else 
                plot_spectrogram(app,app.filtered_signal,app.samplingRate);
            end
            disp("Overlap Length Value Changed")
            
        end

        % Value changed function: DFTSampleValueEditField
        function DFTSampleValueEditFieldValueChanged(app, event)
            app.Ndft_value = app.DFTSampleValueEditField.Value;
            if(app.plot_which == "Unfiltered")
                plot_spectrogram(app,app.signal,app.samplingRate);
            else 
                plot_spectrogram(app,app.filtered_signal,app.samplingRate);
            end
            disp("Overlap Length Value Changed")
            
        end

        % Value changed function: SignalSelectSwitch
        function SignalSelectSwitchValueChanged(app, event)
            app.plot_which = app.SignalSelectSwitch.Value;
            if(app.plot_which == "Unfiltered")
                plot_spectrogram(app,app.signal,app.samplingRate);
            else 
                plot_spectrogram(app,app.filtered_signal,app.samplingRate);
            end
            
        end

        % Value changed function: SignalCategoryDropDown
        function SignalCategoryDropDownValueChanged(app, event)
            app.signalCategory = app.SignalCategoryDropDown.Value;
            
        end

        % Value changed function: SamplingRateDropDown
        function SamplingRateDropDownValueChanged(app, event)
            app.samplingRate = str2double(app.SamplingRateDropDown.Value);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Spectrogram = uiaxes(app.UIFigure);
            title(app.Spectrogram, 'Short-Time Fourier Transform')
            xlabel(app.Spectrogram, 'Time [s]')
            ylabel(app.Spectrogram, 'Frequency  [Hz]')
            zlabel(app.Spectrogram, 'Z')
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.DecodedDataTextAreaLabel = uilabel(app.UIFigure);
            app.DecodedDataTextAreaLabel.Text = 'Decoded Data';
            app.DecodedDataTextArea = uitextarea(app.UIFigure);
            app.DecodedDataTextArea.Editable = 'off';
            app.SignalCategoryDropDownLabel = uilabel(app.UIFigure);
            app.SignalCategoryDropDownLabel.Text = 'Signal Category';
            app.SignalCategoryDropDown = uidropdown(app.UIFigure);
            app.SignalCategoryDropDown.Items = {'Category 1', 'Category 2', 'Category 3'};
            app.SignalCategoryDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalCategoryDropDownValueChanged, true);
            app.SignalCategoryDropDown.Value = 'Category 1';
            app.SamplingRateDropDownLabel = uilabel(app.UIFigure);
            app.SamplingRateDropDownLabel.Text = 'Sampling Rate';
            app.SamplingRateDropDown = uidropdown(app.UIFigure);
            app.SamplingRateDropDown.Items = {'44100', '22050', '11025'};
            app.SamplingRateDropDown.ValueChangedFcn = createCallbackFcn(app, @SamplingRateDropDownValueChanged, true);
            app.SamplingRateDropDown.Value = '44100';
            app.RecordTimeEditFieldLabel = uilabel(app.UIFigure);
            app.RecordTimeEditFieldLabel.Text = 'Record Time';
            app.RecordTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.RecordTimeEditField.ValueChangedFcn = createCallbackFcn(app, @RecordTimeEditFieldValueChanged, true);
            app.RecordTimeEditField.Value = 15;
            app.SNREditFieldLabel = uilabel(app.UIFigure);
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNREditField = uieditfield(app.UIFigure, 'numeric');
            app.SNREditField.Editable = 'off';
            app.WindowLengthEditFieldLabel = uilabel(app.UIFigure);
            app.WindowLengthEditFieldLabel.Text = 'Window Length';
            app.WindowLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowLengthEditField.ValueChangedFcn = createCallbackFcn(app, @WindowLengthEditFieldValueChanged, true);
            app.WindowLengthEditField.Value = 4410;
            app.OverlapLengthEditFieldLabel = uilabel(app.UIFigure);
            app.OverlapLengthEditFieldLabel.Text = 'Overlap Length';
            app.OverlapLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.OverlapLengthEditField.ValueChangedFcn = createCallbackFcn(app, @OverlapLengthEditFieldValueChanged, true);
            app.OverlapLengthEditField.Value = 2205;
            app.DFTSampleValueEditFieldLabel = uilabel(app.UIFigure);
            app.DFTSampleValueEditFieldLabel.Text = 'DFT Sample Value';
            app.DFTSampleValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.DFTSampleValueEditField.ValueChangedFcn = createCallbackFcn(app, @DFTSampleValueEditFieldValueChanged, true);
            app.DFTSampleValueEditField.Value = 8820;
            app.SignalSelectSwitchLabel = uilabel(app.UIFigure);
            app.SignalSelectSwitchLabel.Text = 'Signal Select';
            app.SignalSelectSwitch = uiswitch(app.UIFigure, 'slider');
            app.SignalSelectSwitch.Items = {'Filtered', 'Unfiltered'};
            app.SignalSelectSwitch.ValueChangedFcn = createCallbackFcn(app, @SignalSelectSwitchValueChanged, true);
            app.SignalSelectSwitch.Value = 'Filtered';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Term_Project_Receiver_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end