classdef Term_Project_Transmitter_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DFTSampleValueEditField       matlab.ui.control.NumericEditField
        DFTSampleValueEditFieldLabel  matlab.ui.control.Label
        OverlapLengthEditField        matlab.ui.control.NumericEditField
        OverlapLengthEditFieldLabel   matlab.ui.control.Label
        WindowLengthEditField         matlab.ui.control.NumericEditField
        WindowLengthEditFieldLabel    matlab.ui.control.Label
        InputDataEditField            matlab.ui.control.EditField
        InputDataEditFieldLabel       matlab.ui.control.Label
        SamplingRateDropDown          matlab.ui.control.DropDown
        SamplingRateDropDownLabel     matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        SignalCategoryDropDown        matlab.ui.control.DropDown
        SignalCategoryDropDownLabel   matlab.ui.control.Label
        Spectrogram                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        studentNumber = '2516987'; % Student Number (STRING !!!)
        signalCategory = 'Category 1';
        samplingRate = 44100;
        signal = 0;
        inputData = 0;
        delta_f = 100;
        m_fsk = 2;
        hop_period = 1;

        M_value = 4410;
        L_value = 2205;
        Ndft_value = 8820;
    end
    
    methods (Access = private)
        
        function main(app)
            disp('RAN PROGRAM');
            disp("Generated Frequency Table:")
            freqTable = generateFreqTable(app, app.studentNumber, app.signalCategory)
            disp("Binary Data:")
            binaryData = binaryConverter(app, app.inputData);
            disp("Padded Data:")
            padded_data = padder(app, app.signalCategory, binaryData);
            disp("Selected Frequencies:")
            selectedFreqs = selectFreqsfromTable(app, padded_data, freqTable)
            disp("Modulated Frequencies:")
            modulatedFreqs = modulate(app, padded_data, selectedFreqs, app.signalCategory)
            disp("Signal is generating")
            app.signal = generateSignal(app, modulatedFreqs, app.hop_period, app.samplingRate);
            disp("Signal is plotting")
            plot_spectrogram(app,app.signal,app.samplingRate);
            si = app.signal;
            save("signal.mat", "si");
            disp("Sound out")
            sound_out(app, app.signal, app.samplingRate)
        end
        
        function freqTable = generateFreqTable(app, studentNumber, category)
            b_cat1 = str2double(studentNumber(6));
            S1 = 100*(mod(str2double(studentNumber(7)),5));
            row1 = [1000, 1500, 2000, 2500, 3000, 3500, 4000, 4500, 5000];

            b_cat2 = str2double(studentNumber(5));
            S2 = 100*str2double(studentNumber(7));
            row2 = [1500, 2500, 3500, 4500, 5500, 6500, 7500, 8500];

            S3 = 100*str2double(studentNumber(7));
            row3 = [1000, 3000, 5000, 7000, 9000, 11000];
                        
            if category == 'Category 1'
                if mod(b_cat1,2) == 0
                    b_cat1 = 1;
                else
                    b_cat1 = -1;
                end

                freqTable = row1 + b_cat1*S1; 

            elseif category == 'Category 2'
                if mod(b_cat2, 2) == 0
                    b_cat2 = 1;
                else
                    b_cat2 = -1;
                end

                freqTable = row2 + b_cat2*S2;                 

            elseif category == 'Category 3'
                freqTable = row3 + S3;
            else
                disp('WRONG CATEGORY');
            end
        end

        function selectedFreqs = selectFreqsfromTable(app, inputData, freqTable)
            stringInputData = num2str(inputData);
            [~, inputdataLength] = size(stringInputData);
            [~, freqtableLength] = size(freqTable);
            selectedFreqs = zeros(1, inputdataLength);
            rng(31); % Optional: Seed the random number generator for different results each time
            randomValues = randi([1, freqtableLength], 1, inputdataLength) % Generate inputdataLength random integers between 1 and freqtableLength
            for i = 1:inputdataLength 
                selectedFreqs(1, i) = freqTable(randomValues(1, i));
            end
        end

        function modulatedFreqs = modulate(app, inputData, selectedFreqs, signalCategory)
            
            if signalCategory == 'Category 1'
                divider = 1;
            elseif signalCategory == 'Category 2'
                divider = 2;
            elseif signalCategory == 'Category 3'
                divider = 3;
            else
                disp("Invalid Category!!")
            end

            modulatedFreqs = zeros(size(inputData)./[1 divider])
            stringData = num2str(inputData);
            [~ , inputLenght] = size(stringData);
            for i = 1:(inputLenght/divider)
                inputDigit = stringData(1,(i*divider - divider + 1):i*divider);
                if divider == 1
                    if inputDigit == '1'
                        modulatedFreqs(i) = selectedFreqs(i) + 100*1;
                    else
                        modulatedFreqs(i) = selectedFreqs(i) - 100*1;
                    end
                elseif divider == 2
                    if inputDigit == '00'
                        modulatedFreqs(i) = selectedFreqs(i) + 150*-2;
                    elseif inputDigit == '01'
                        modulatedFreqs(i) = selectedFreqs(i) + 150*-1;
                    elseif inputDigit == '10'
                        modulatedFreqs(i) = selectedFreqs(i) + 150*+1;
                    elseif inputDigit == '11'
                        modulatedFreqs(i) = selectedFreqs(i) + 150*+2;
                    end
                elseif divider == 3
                    if inputDigit == '000'
                        modulatedFreqs(i) = selectedFreqs(i) + 200*-4;
                    elseif inputDigit == '001'
                        modulatedFreqs(i) = selectedFreqs(i) + 200*-3;
                    elseif inputDigit == '010'
                        modulatedFreqs(i) = selectedFreqs(i) + 200*-2;
                    elseif inputDigit == '011'
                        modulatedFreqs(i) = selectedFreqs(i) + 200*-1;
                    elseif inputDigit == '100'
                        modulatedFreqs(i) = selectedFreqs(i) + 200*+1;
                    elseif inputDigit == '101'
                        modulatedFreqs(i) = selectedFreqs(i) + 200*+2;
                    elseif inputDigit == '110'
                        modulatedFreqs(i) = selectedFreqs(i) + 200*+3;
                    elseif inputDigit == '111'
                        modulatedFreqs(i) = selectedFreqs(i) + 200*+4;
                    end
                end

            end
        end

        function signal = generateSignal(app, freqs, period, fs)    
            num_periods = length(freqs);
            t = 0: (1/fs) : (period-1/fs);
            signal = zeros(1, length(t)*num_periods);
            current_phase = 0; % Initialize the phase to ensure continuity
        
            for i = 1:num_periods
                curr_freq = freqs(i);
                % Generate the current period with a phase offset
                current_signal = sin(2*pi*curr_freq*t + current_phase);
                % Append the current signal to the final signal
                signal((i-1)*length(t)+1 : i*length(t)) = current_signal;
                % Calculate the phase at the end of the current signal
                current_phase = mod(current_phase + 2*pi*curr_freq*period, 2*pi);
            end
        
            % Generate the initial and final segments with f_initial = 10 kHz
            f_initial = 15e3; % 15kHz
            signal_indicator = sin(2*pi*f_initial*t);
        
            % Concatenate the initial signal, main signal, and final signal
            signal = [signal_indicator, signal, signal_indicator];
        end

        function plot_spectrogram(app,signal,fs)
            M = app.M_value; % window length M
            L = app.L_value ; % overlap length L
            g = bartlett(M); % Window each segment with a Barlett Window
            
            Ndft = app.Ndft_value; % Evaluate the discrete Fourier transform of each segment at  N
            
            ts = 0:1/fs:(length(signal)/fs);
            segs = framesig(1:length(signal),M,OverlapLength=L);
            X = fft(signal(segs).*g,Ndft);
            % To find the time values, divide the time vector into overlapping segments. 
            % The time values are the midpoints of the segments, with each segment treated as an interval open at the lower end.
            framedT = ts(segs);
            tint = mean(framedT(2:end,:));  
            % To find the frequency values, specify a Nyquist interval closed at zero frequency and open at the lower end.
            fint = 0:fs/Ndft:fs-fs/Ndft;
            
            imagesc(app.Spectrogram,tint,fint,abs(X).^2);
            ylim(app.Spectrogram,[0,fs/2]) 
            xlim(app.Spectrogram,[0,length(signal)/fs])

            disp("Size of X:")
            disp(size(X))
        end

        
        function padded_signal = padder(app, signalCategory, inputData)
            stringInputData = num2str(inputData);
            [~, inputdataLength] = size(stringInputData);

            if signalCategory == 'Category 1'
                divider = 1;
            elseif signalCategory == 'Category 2'
                divider = 2;
            elseif signalCategory == 'Category 3'
                divider = 3;
            else
                disp("Invalid Category!!")
            end

            padLength = mod(divider - mod(inputdataLength, divider), divider);
            
            zero_padding = repmat('0', 1, padLength);

            zero_padding

            padded_signal = [stringInputData , zero_padding]

            disp("Padded Signal")
            size(padded_signal)
            padded_signal
        end
        
        function sound_out(app,signal,fs)
            sound(signal,fs);
        end
        
        function binaryData = binaryConverter(app, inputData)
            binaryData = reshape(dec2bin(double(inputData), 8).', 1, []);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SignalCategoryDropDown
        function SignalCategoryDropDownValueChanged(app, event)
            app.signalCategory = app.SignalCategoryDropDown.Value;
            if app.signalCategory == 'Category 1'
                app.delta_f = 100;
                app.m_fsk = 2
                app.hop_period = 1
            elseif app.signalCategory == 'Category 2'
                app.delta_f = 150;
                app.m_fsk = 4
                app.hop_period = 0.75
            elseif app.signalCategory == 'Category 3'
                app.delta_f = 200;
                app.m_fsk = 8
                app.hop_period = 0.5
            else
                disp('INCORRECT CATEGORY')
            end
            
            disp("Signal Category, delta_f, m_fsk, hop_period:")
            app.signalCategory, app.delta_f, app.m_fsk, app.hop_period
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            main(app);
        end

        % Value changed function: SamplingRateDropDown
        function SamplingRateDropDownValueChanged(app, event)
            app.samplingRate = str2double(app.SamplingRateDropDown.Value);
            disp("Sampling Rate:")
            app.samplingRate
        end

        % Value changed function: InputDataEditField
        function InputDataEditFieldValueChanged(app, event)
            app.inputData = app.InputDataEditField.Value;
        end

        % Value changed function: WindowLengthEditField
        function WindowLengthEditFieldValueChanged(app, event)
            app.M_value = app.WindowLengthEditField.Value;
            plot_spectrogram(app,app.signal,app.samplingRate);
            disp("Window Length Value Changed")
            
        end

        % Value changed function: OverlapLengthEditField
        function OverlapLengthEditFieldValueChanged(app, event)
            app.L_value = app.OverlapLengthEditField.Value;
            plot_spectrogram(app,app.signal,app.samplingRate);
            disp("Overlap Length Value Changed")
            
        end

        % Value changed function: DFTSampleValueEditField
        function DFTSampleValueEditFieldValueChanged(app, event)
            app.Ndft_value = app.DFTSampleValueEditField.Value;
            plot_spectrogram(app,app.signal,app.samplingRate);
            disp("Overlap Length Value Changed")
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Spectrogram = uiaxes(app.UIFigure);
            title(app.Spectrogram, 'Short-Time Fourier Transform')
            xlabel(app.Spectrogram, 'Time [s]')
            ylabel(app.Spectrogram, 'Frequency  [Hz]')
            zlabel(app.Spectrogram, 'Z')
            app.SignalCategoryDropDownLabel = uilabel(app.UIFigure);
            app.SignalCategoryDropDownLabel.Text = 'Signal Category';
            app.SignalCategoryDropDown = uidropdown(app.UIFigure);
            app.SignalCategoryDropDown.Items = {'Category 1', 'Category 2', 'Category 3'};
            app.SignalCategoryDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalCategoryDropDownValueChanged, true);
            app.SignalCategoryDropDown.Value = 'Category 1';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SamplingRateDropDownLabel = uilabel(app.UIFigure);
            app.SamplingRateDropDownLabel.Text = 'Sampling Rate';
            app.SamplingRateDropDown = uidropdown(app.UIFigure);
            app.SamplingRateDropDown.Items = {'44100', '22050', '11025'};
            app.SamplingRateDropDown.ValueChangedFcn = createCallbackFcn(app, @SamplingRateDropDownValueChanged, true);
            app.SamplingRateDropDown.Value = '44100';
            app.InputDataEditFieldLabel = uilabel(app.UIFigure);
            app.InputDataEditFieldLabel.Text = 'Input Data';
            app.InputDataEditField = uieditfield(app.UIFigure, 'text');
            app.InputDataEditField.ValueChangedFcn = createCallbackFcn(app, @InputDataEditFieldValueChanged, true);
            app.WindowLengthEditFieldLabel = uilabel(app.UIFigure);
            app.WindowLengthEditFieldLabel.Text = 'Window Length';
            app.WindowLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowLengthEditField.ValueChangedFcn = createCallbackFcn(app, @WindowLengthEditFieldValueChanged, true);
            app.WindowLengthEditField.Value = 4410;
            app.OverlapLengthEditFieldLabel = uilabel(app.UIFigure);
            app.OverlapLengthEditFieldLabel.Text = 'Overlap Length';
            app.OverlapLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.OverlapLengthEditField.ValueChangedFcn = createCallbackFcn(app, @OverlapLengthEditFieldValueChanged, true);
            app.OverlapLengthEditField.Value = 2205;
            app.DFTSampleValueEditFieldLabel = uilabel(app.UIFigure);
            app.DFTSampleValueEditFieldLabel.Text = 'DFT Sample Value';
            app.DFTSampleValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.DFTSampleValueEditField.ValueChangedFcn = createCallbackFcn(app, @DFTSampleValueEditFieldValueChanged, true);
            app.DFTSampleValueEditField.Value = 8820;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Term_Project_Transmitter_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end